classdef Impedance_wErrorBar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CircuitDropDownLabel          matlab.ui.control.Label
        CircuitDropDown               matlab.ui.control.DropDown
        PlotTypeDropDownLabel         matlab.ui.control.Label
        PlotTypeDropDown              matlab.ui.control.DropDown
        ElectrodeConfigDropDownLabel  matlab.ui.control.Label
        ElectrodeConfigDropDown       matlab.ui.control.DropDown
        LoadFileButton                matlab.ui.control.Button
        Filename                      matlab.ui.control.Label
        PlotButton                    matlab.ui.control.Button
        NoofRepeatsEditFieldLabel     matlab.ui.control.Label
        NoofRepeatsEditField          matlab.ui.control.NumericEditField
        XLimitEditFieldLabel          matlab.ui.control.Label
        XMin                          matlab.ui.control.NumericEditField
        XMax                          matlab.ui.control.NumericEditField
        XTicksEditFieldLabel          matlab.ui.control.Label
        XTicks                        matlab.ui.control.EditField
        YMax                          matlab.ui.control.NumericEditField
        YLimitEditFieldLabel          matlab.ui.control.Label
        YMin                          matlab.ui.control.NumericEditField
        YTicksEditFieldLabel          matlab.ui.control.Label
        YTicks                        matlab.ui.control.EditField
        GetButton                     matlab.ui.control.Button
        SetButton                     matlab.ui.control.Button
        AxisDropDownLabel             matlab.ui.control.Label
        AxisDropDown                  matlab.ui.control.DropDown
        RSenseEditFieldLabel          matlab.ui.control.Label
        RSenseEditField               matlab.ui.control.NumericEditField
        SaveAllModeButton             matlab.ui.control.Button
        ErrorBarCheckBox              matlab.ui.control.CheckBox
        IndividualCheckBox            matlab.ui.control.CheckBox
        GeoFacEditFieldLabel          matlab.ui.control.Label
        GeoFacEditField               matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        file
        path
        ax1
        ax2
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc
            addpath('C:\Users\Sanjay Pandit\Documents\Study\PhD\Resistivity Instrument\PythonApp\ImpedanceMeter\model\matlab')
        end

        % Button pushed function: LoadFileButton
        function LoadFileButtonPushed(app, event)
            [app.file, app.path] = uigetfile('*.xlsx');
            if app.file ~= 0
                app.Filename.Text = app.file;
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            filename = strcat(app.path, app.file);
            if strcmp(app.PlotTypeDropDown.Value, 'Voltage')
                if strcmp(app.ElectrodeConfigDropDown.Value, 'Two Electrode')
                    if strcmp(app.CircuitDropDown.Value, 'H3C')
                        h3c = ModeH3C(filename, app.RSenseEditField.Value, app.NoofRepeatsEditField.Value);
                        h3c.process_for_two_electrodes_configuration();
                        freq = h3c.freq;
                        vratio = h3c.vratio;
                        dvratio = h3c.dvratio;
                        pdiff = h3c.pdiff;
                        dpdiff = h3c.dpdiff;
                        
                        freq_ind = h3c.freq_ind;
                        vratio_ind = h3c.vratio_ind;
                        pdiff_ind = h3c.pdiff_ind;
                    end
                    if strcmp(app.CircuitDropDown.Value, 'A2B')
                        a2b = ModeA2BC(filename, app.RSenseEditField.Value, app.NoofRepeatsEditField.Value);
                        a2b.process_for_two_electrodes_configuration();
                        freq = a2b.freq;
                        vratio = a2b.vratio;
                        dvratio = a2b.dvratio;
                        pdiff = a2b.pdiff;
                        dpdiff = a2b.dpdiff;
                        
                        freq_ind = a2b.freq_ind;
                        vratio_ind = a2b.vratio_ind;
                        pdiff_ind = a2b.pdiff_ind;
                    end
                end
                if strcmp(app.ElectrodeConfigDropDown.Value, 'Four Electrode')
                    if strcmp(app.CircuitDropDown.Value, 'H3C')
                        h3c = ModeH3C(filename, app.RSenseEditField.Value, app.NoofRepeatsEditField.Value);
                        h3c.process_for_four_electrodes_configuration();
                        freq = h3c.freq;
                        vratio = h3c.vratio;
                        dvratio = h3c.dvratio;
                        pdiff = h3c.pdiff;
                        dpdiff = h3c.dpdiff;
                        
                        freq_ind = h3c.freq_ind;
                        vratio_ind = h3c.vratio_ind;
                        pdiff_ind = h3c.pdiff_ind;
                    end
                    if strcmp(app.CircuitDropDown.Value, 'A2B')
                        a2b = ModeA2BC(filename, app.RSenseEditField.Value, app.NoofRepeatsEditField.Value);
                        a2b.process_for_four_electrodes_configuration();
                        freq = a2b.freq;
                        vratio = a2b.vratio;
                        dvratio = a2b.dvratio;
                        pdiff = a2b.pdiff;
                        dpdiff = a2b.dpdiff;
                        
                        freq_ind = a2b.freq_ind;
                        vratio_ind = a2b.vratio_ind;
                        pdiff_ind = a2b.pdiff_ind;
                    end
                end
                fig = figure('Units', 'pixels', ...
                    'Position', [200 200 500 375]);
                set(fig, ...
                    'Color',        'w');
                app.ax1 = subplot(2, 1, 1);
                hold on
                box on
                lines = gobjects(5);
                if app.IndividualCheckBox.Value == true
                    for i = 1:app.NoofRepeatsEditField.Value
                        lines(i) = line(freq_ind(i, :), vratio_ind(i, :));
                        set(lines(i), ...
                            'LineStyle',        'none', ...
                            'Marker',           'o', ...
                            'MarkerEdgeColor',   'k', ...
                            'MarkerFaceColor',  [.3 .3 .3] + 0.1*i);
                    end
                else
                    vRatio = line(freq, vratio);
                    set(vRatio, ...
                        'LineStyle',        'none', ...
                        'Marker',           'o', ...
                        'MarkerEdgeColor',   'k', ...
                        'MarkerFaceColor',  [.7 .7 .7]);
                    if app.ErrorBarCheckBox.Value == true
                        vRatioError = errorbar(freq, vratio, dvratio);
                        set(vRatioError, ...
                            'LineStyle',    'none', ...
                            'Color',        [.3 .3 .3]);   
                    end
                end
                
                vrTitle = title('Measured Voltage Ratio');
                vrXLabel = ylabel('Voltage Ratio (${V_i}/{V_a}$)', 'Interpreter', 'latex');
                vrYLabel = xlabel('Frequency ($Hz$)', 'Interpreter', 'latex');
                
                set(gca, ...
                    'FontName',     'Arial', ...
                    'FontSize',     10, ...
                    'XScale',       'log', ...
                    'Box',          'on', ...
                    'TickDir',      'in', ...
                    'TickLength',   [.02 .02], ...
                    'XMinorTick',   'on', ...
                    'YMinorTick',   'off', ...
                    'YGrid',        'on', ...
                    'GridLineStyle',':', ...
                    'GridColor',    'k', ...
                    'GridAlpha',    0.5, ...
                    'LineWidth',    1);
                set([vrTitle, vrXLabel, vrYLabel], ...
                    'FontName',     'Arial');
                set([vrXLabel, vrYLabel], ...
                    'FontSize',     10);
                set(vrTitle, ...
                    'FontSize',     12, ...
                    'FontWeight',   'bold');
                
                hold off
                app.ax2 = subplot(2, 1, 2);
                hold on
                if app.IndividualCheckBox.Value == true
                    for i = 1:app.NoofRepeatsEditField.Value
                        lines(i) = line(freq_ind(i, :), pdiff_ind(i, :));
                        set(lines(i), ...
                            'LineStyle',        'none', ...
                            'Marker',           'o', ...
                            'MarkerEdgeColor',   'k', ...
                            'MarkerFaceColor',  [.3 .3 .3] + 0.1*i);
                    end
                else
                    pDiff = line(freq, pdiff);
                    set(pDiff, ...
                        'LineStyle',        'none', ...
                        'Marker',           'o', ...
                        'MarkerEdgeColor',   'k', ...
                        'MarkerFaceColor',  [.7 .7 .7]);
                    if app.ErrorBarCheckBox.Value == true
                        pDiffError = errorbar(freq, pdiff, dpdiff);
                        set(pDiffError, ...
                            'LineStyle',    'none', ...
                            'Color',        [.3 .3 .3]);
                    end
                end
                pdTitle = title('Measured Phase Difference');
                pdYLabel = ylabel('Phase Difference ($mRad$)', 'Interpreter', 'latex');
                pdXLabel = xlabel('Frequency ($Hz$)', 'Interpreter', 'latex');
                
                set(gca, ...
                    'FontName',     'Arial', ...
                    'FontSize',     10, ...
                    'XScale',       'log', ...
                    'Box',          'on', ...
                    'TickDir',      'in', ...
                    'TickLength',   [.02 .02], ...
                    'XMinorTick',   'on', ...
                    'YMinorTick',   'off', ...
                    'YGrid',        'on', ...
                    'GridLineStyle',':', ...
                    'GridColor',    'k', ...
                    'GridAlpha',    0.5, ...
                    'LineWidth',    1);
                set([pdTitle, pdXLabel, pdYLabel], ...
                    'FontName',     'Arial');
                set([pdTitle, pdYLabel], ...
                    'FontSize',     10);
                set(pdTitle, ...
                    'FontSize',     12, ...
                    'FontWeight',   'bold');
            end
            
            if strcmp(app.PlotTypeDropDown.Value, 'Resistance')
                if strcmp(app.ElectrodeConfigDropDown.Value, 'Two Electrode')
                    if strcmp(app.CircuitDropDown.Value, 'H3C')
                        h3c = ModeH3C(filename, app.RSenseEditField.Value, app.NoofRepeatsEditField.Value);
                        h3c.process_for_two_electrodes_configuration();
                        freq = h3c.freq;
                        absz = h3c.zabs * app.GeoFacEditField.Value;
                        dabsz = h3c.dzabs * app.GeoFacEditField.Value;
                        pdiff = h3c.zphase;
                        dpdiff = h3c.dzphase;
                        
                        freq_ind = h3c.freq_ind;
                        absz_ind = h3c.zabs_ind * app.GeoFacEditField.Value;
                        pdiff_ind = h3c.zphase_ind;
                    end
                    if strcmp(app.CircuitDropDown.Value, 'A2B')
                        a2b = ModeA2BC(filename, app.RSenseEditField.Value, app.NoofRepeatsEditField.Value);
                        a2b.process_for_two_electrodes_configuration();
                        freq = a2b.freq;
                        absz = a2b.zabs * app.GeoFacEditField.Value;
                        dabsz = a2b.dzabs * app.GeoFacEditField.Value;
                        pdiff = a2b.zphase;
                        dpdiff = a2b.dzphase;
                        
                        freq_ind = a2b.freq_ind;
                        absz_ind = a2b.zabs_ind * app.GeoFacEditField.Value;
                        pdiff_ind = a2b.zphase_ind;
                    end
                end
                if strcmp(app.ElectrodeConfigDropDown.Value, 'Four Electrode')
                    if strcmp(app.CircuitDropDown.Value, 'H3C')
                        h3c = ModeH3C(filename, app.RSenseEditField.Value, app.NoofRepeatsEditField.Value);
                        h3c.process_for_four_electrodes_configuration();
                        freq = h3c.freq;
                        absz = h3c.zabs * app.GeoFacEditField.Value;
                        dabsz = h3c.dzabs * app.GeoFacEditField.Value;
                        pdiff = h3c.zphase;
                        dpdiff = h3c.dzphase;
                        
                        freq_ind = h3c.freq_ind;
                        absz_ind = h3c.zabs_ind * app.GeoFacEditField.Value;
                        pdiff_ind = h3c.zphase_ind;
                    end
                    if strcmp(app.CircuitDropDown.Value, 'A2B')
                        a2b = ModeA2BC(filename, app.RSenseEditField.Value, app.NoofRepeatsEditField.Value);
                        a2b.process_for_four_electrodes_configuration();
                        freq = a2b.freq;
                        absz = a2b.zabs * app.GeoFacEditField.Value;
                        dabsz = a2b.dzabs * app.GeoFacEditField.Value;
                        pdiff = a2b.zphase;
                        dpdiff = a2b.dzphase;
                        
                        freq_ind = a2b.freq_ind;
                        absz_ind = a2b.zabs_ind * app.GeoFacEditField.Value;
                        pdiff_ind = a2b.zphase_ind;
                    end
                end
                fig = figure('Units', 'pixels', ...
                    'Position', [200 200 500 375]);
                set(fig, ...
                    'Color',        'w');
                app.ax1 = subplot(2, 1, 1);
                hold on
                box on
                
                if app.IndividualCheckBox.Value == true
                    for i = 1:app.NoofRepeatsEditField.Value
                        lines(i) = line(freq_ind(i, :), absz_ind(i, :));
                        set(lines(i), ...
                            'LineStyle',        'none', ...
                            'Marker',           'o', ...
                            'MarkerEdgeColor',   'k', ...
                            'MarkerFaceColor',  [.3 .3 .3] + 0.1*i);
                    end
                else
                    absZ = line(freq, absz);
                    set(absZ, ...
                        'LineStyle',        'none', ...
                        'Marker',           'o', ...
                        'MarkerEdgeColor',   'k', ...
                        'MarkerFaceColor',  [.7 .7 .7]);
                    if app.ErrorBarCheckBox.Value == true
                        absZError = errorbar(freq, absz, dabsz);
                        set(absZError, ...
                            'LineStyle',    'none', ...
                            'Color',        [.3 .3 .3]);
                    end
                end
                azTitle = title('Measured Resistivity');
                azXLabel = ylabel('Resistivity ($\Omega.m$)', 'Interpreter', 'latex');
                azYLabel = xlabel('Frequency ($Hz$)', 'Interpreter', 'latex');
                
                set(gca, ...
                    'FontName',     'Arial', ...
                    'FontSize',     10, ...
                    'XScale',       'log', ...
                    'Box',          'on', ...
                    'TickDir',      'in', ...
                    'TickLength',   [.02 .02], ...
                    'XMinorTick',   'on', ...
                    'YMinorTick',   'off', ...
                    'YGrid',        'on', ...
                    'GridLineStyle',':', ...
                    'GridColor',    'k', ...
                    'GridAlpha',    0.5, ...
                    'LineWidth',    1);
                set([azTitle, azXLabel, azYLabel], ...
                    'FontName',     'Arial');
                set([azXLabel, azYLabel], ...
                    'FontSize',     10);
                set(azTitle, ...
                    'FontSize',     12, ...
                    'FontWeight',   'bold');
                    
                hold off
                app.ax2 = subplot(2, 1, 2);
                hold on
                
                if app.IndividualCheckBox.Value == true
                    for i = 1:app.NoofRepeatsEditField.Value
                        lines(i) = line(freq_ind(i, :), pdiff_ind(i, :));
                        set(lines(i), ...
                            'LineStyle',        'none', ...
                            'Marker',           'o', ...
                            'MarkerEdgeColor',   'k', ...
                            'MarkerFaceColor',  [.3 .3 .3] + 0.1*i);
                    end
                else
                    pDiff = line(freq, pdiff);
                    set(pDiff, ...
                        'LineStyle',        'none', ...
                        'Marker',           'o', ...
                        'MarkerEdgeColor',   'k', ...
                        'MarkerFaceColor',  [.7 .7 .7]);
                    if app.ErrorBarCheckBox.Value == true
                        pDiffError = errorbar(freq, pdiff, dpdiff);
                        set(pDiffError, ...
                            'LineStyle',    'none', ...
                            'Color',        [.3 .3 .3]);
                    end
                end
                
                pdTitle = title('Measured Phase Difference');
                pdYLabel = ylabel('Phase Difference ($mRad$)', 'Interpreter', 'latex');
                pdXLabel = xlabel('Frequency ($Hz$)', 'Interpreter', 'latex');
                
                set(gca, ...
                    'FontName',     'Arial', ...
                    'FontSize',     10, ...
                    'XScale',       'log', ...
                    'Box',          'on', ...
                    'TickDir',      'in', ...
                    'TickLength',   [.02 .02], ...
                    'XMinorTick',   'on', ...
                    'YMinorTick',   'off', ...
                    'YGrid',        'on', ...
                    'GridLineStyle',':', ...
                    'GridColor',    'k', ...
                    'GridAlpha',    0.5, ...
                    'LineWidth',    1);
                set([pdTitle, pdXLabel, pdYLabel], ...
                    'FontName',     'Arial');
                set([pdXLabel, pdYLabel], ...
                    'FontSize',     10);
                set(pdTitle, ...
                    'FontSize',     12, ...
                    'FontWeight',   'bold');
            end
        end

        % Button pushed function: GetButton
        function GetButtonPushed(app, event)
            if strcmp(app.AxisDropDown.Value, 'Ax1')
                nx = xlim(app.ax1);
                mx = xticks(app.ax1);
                ny = ylim(app.ax1);
                my = yticks(app.ax1);
            end
            
            if strcmp(app.AxisDropDown.Value, 'Ax2')
                nx = xlim(app.ax2);
                mx = xticks(app.ax2);
                ny = ylim(app.ax2);
                my = yticks(app.ax2);
            end
            
            app.XMin.Value = nx(1);
            app.XMax.Value = nx(2);
            
            x_ticks = '';
            for i = 1:length(mx)
                if i ~= length(mx)
                    x_ticks = strcat(x_ticks, num2str(mx(i)), ', ');
                else
                    x_ticks = strcat(x_ticks, num2str(mx(i)));
                end
            end
            app.XTicks.Value = x_ticks;
            
            app.YMin.Value = ny(1);
            app.YMax.Value = ny(2);
            
            y_ticks = '';
            for i = 1:length(my)
                if i ~= length(my)
                    y_ticks = strcat(y_ticks, num2str(my(i)), ', ');
                else
                    y_ticks = strcat(y_ticks, num2str(my(i)));
                end
            end
            app.YTicks.Value = y_ticks;
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            x_limits = [app.XMin.Value, app.XMax.Value];
            x_ticks = str2num(app.XTicks.Value);
            
            y_limits = [app.YMin.Value, app.YMax.Value];
            y_ticks = str2num(app.YTicks.Value);
            
            if strcmp(app.AxisDropDown.Value, 'Ax1')
                xlim(app.ax1, x_limits)
                xticks(app.ax1, x_ticks)
                ylim(app.ax1, y_limits)
                yticks(app.ax1, y_ticks)
            end
            
            if strcmp(app.AxisDropDown.Value, 'Ax2')
                xlim(app.ax2, x_limits)
                xticks(app.ax2, x_ticks)
                ylim(app.ax2, y_limits)
                yticks(app.ax2, y_ticks)
            end 
        end

        % Button pushed function: SaveAllModeButton
        function SaveAllModeButtonPushed(app, event)
            [savefile, savepath] = uiputfile('*.xlsx');
            if savefile ~= 0
                if ~contains(savefile, '.xlsx')
                    savefile = strcat(savefile, '.xlsx');
                end
                savefilename = strcat(savepath, savefile);
                if app.CircuitDropDown.Value == 'H3C'
                    h3c = ModeH3C(strcat(app.path, app.file), app.RSenseEditField.Value, app.NoofRepeatsEditField.Value);
                    h3c.save_to_file(savefilename)
                end
                if app.CircuitDropDown.Value == 'A2B'
                    a2bc = ModeA2BC(strcat(app.path, app.file), app.RSenseEditField.Value, app.NoofRepeatsEditField.Value);
                    a2bc.save_to_file(savefilename)
                end
            end 
        end

        % Value changed function: ErrorBarCheckBox
        function ErrorBarCheckBoxValueChanged(app, event)
            value = app.ErrorBarCheckBox.Value;
            if value == true
                app.IndividualCheckBox.Value = false;
            end
            
        end

        % Value changed function: IndividualCheckBox
        function IndividualCheckBoxValueChanged(app, event)
            value = app.IndividualCheckBox.Value;
            if value == true
                app.ErrorBarCheckBox.Value = false;
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.CircuitDropDownLabel = uilabel(app.UIFigure);
            app.CircuitDropDownLabel.Text = 'Circuit';
            app.CircuitDropDown = uidropdown(app.UIFigure);
            app.CircuitDropDown.Items = {'H3C', 'A2B'};
            app.CircuitDropDown.Value = 'H3C';
            app.PlotTypeDropDownLabel = uilabel(app.UIFigure);
            app.PlotTypeDropDownLabel.Text = 'Plot Type';
            app.PlotTypeDropDown = uidropdown(app.UIFigure);
            app.PlotTypeDropDown.Items = {'Voltage', 'Resistance'};
            app.PlotTypeDropDown.Value = 'Voltage';
            app.ElectrodeConfigDropDownLabel = uilabel(app.UIFigure);
            app.ElectrodeConfigDropDownLabel.Text = 'Electrode Config';
            app.ElectrodeConfigDropDown = uidropdown(app.UIFigure);
            app.ElectrodeConfigDropDown.Items = {'Two Electrode', 'Four Electrode'};
            app.ElectrodeConfigDropDown.Value = 'Two Electrode';
            app.LoadFileButton = uibutton(app.UIFigure, 'push');
            app.LoadFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFileButton.Text = 'Load File';
            app.Filename = uilabel(app.UIFigure);
            app.Filename.Text = 'Filename';
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.NoofRepeatsEditFieldLabel = uilabel(app.UIFigure);
            app.NoofRepeatsEditFieldLabel.Text = 'No. of Repeats';
            app.NoofRepeatsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NoofRepeatsEditField.Limits = [1 10];
            app.NoofRepeatsEditField.Value = 5;
            app.XLimitEditFieldLabel = uilabel(app.UIFigure);
            app.XLimitEditFieldLabel.Text = 'X Limit';
            app.XMin = uieditfield(app.UIFigure, 'numeric');
            app.XMax = uieditfield(app.UIFigure, 'numeric');
            app.XTicksEditFieldLabel = uilabel(app.UIFigure);
            app.XTicksEditFieldLabel.Text = 'X Ticks';
            app.XTicks = uieditfield(app.UIFigure, 'text');
            app.YMax = uieditfield(app.UIFigure, 'numeric');
            app.YLimitEditFieldLabel = uilabel(app.UIFigure);
            app.YLimitEditFieldLabel.Text = 'Y Limit';
            app.YMin = uieditfield(app.UIFigure, 'numeric');
            app.YTicksEditFieldLabel = uilabel(app.UIFigure);
            app.YTicksEditFieldLabel.Text = 'Y Ticks';
            app.YTicks = uieditfield(app.UIFigure, 'text');
            app.GetButton = uibutton(app.UIFigure, 'push');
            app.GetButton.ButtonPushedFcn = createCallbackFcn(app, @GetButtonPushed, true);
            app.GetButton.Text = 'Get';
            app.SetButton = uibutton(app.UIFigure, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.AxisDropDownLabel = uilabel(app.UIFigure);
            app.AxisDropDownLabel.Text = 'Axis';
            app.AxisDropDown = uidropdown(app.UIFigure);
            app.AxisDropDown.Items = {'Ax1', 'Ax2'};
            app.AxisDropDown.Value = 'Ax1';
            app.RSenseEditFieldLabel = uilabel(app.UIFigure);
            app.RSenseEditFieldLabel.Text = 'R Sense';
            app.RSenseEditField = uieditfield(app.UIFigure, 'numeric');
            app.RSenseEditField.Value = 998.5;
            app.SaveAllModeButton = uibutton(app.UIFigure, 'push');
            app.SaveAllModeButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAllModeButtonPushed, true);
            app.SaveAllModeButton.Text = 'Save All Mode';
            app.ErrorBarCheckBox = uicheckbox(app.UIFigure);
            app.ErrorBarCheckBox.ValueChangedFcn = createCallbackFcn(app, @ErrorBarCheckBoxValueChanged, true);
            app.ErrorBarCheckBox.Text = 'Error Bar';
            app.IndividualCheckBox = uicheckbox(app.UIFigure);
            app.IndividualCheckBox.ValueChangedFcn = createCallbackFcn(app, @IndividualCheckBoxValueChanged, true);
            app.IndividualCheckBox.Text = 'Individual';
            app.GeoFacEditFieldLabel = uilabel(app.UIFigure);
            app.GeoFacEditFieldLabel.Text = 'GeoFac';
            app.GeoFacEditField = uieditfield(app.UIFigure, 'numeric');
            app.GeoFacEditField.Value = 5;
        end
    end

    methods (Access = public)

        % Construct app
        function app = Impedance_wErrorBar

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end